classdef ECO_design_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ABSimulationTab              matlab.ui.container.Tab
        TabGroup3                    matlab.ui.container.TabGroup
        SimulationtabTab             matlab.ui.container.Tab
        StartButton                  matlab.ui.control.Button
        LogEditFieldLabel            matlab.ui.control.Label
        LogEditField                 matlab.ui.control.EditField
        NumberofdeliverystationsDropDownLabel  matlab.ui.control.Label
        NumberofdeliverystationsDropDown  matlab.ui.control.DropDown
        ChassimaterialDropDownLabel  matlab.ui.control.Label
        ChassimaterialDropDown       matlab.ui.control.DropDown
        UIAxes3                      matlab.ui.control.UIAxes
        RouteTab                     matlab.ui.container.Tab
        UIAxes4                      matlab.ui.control.UIAxes
        ResultTab                    matlab.ui.container.Tab
        ComputationalresultsPanel    matlab.ui.container.Panel
        VehicleDropDownLabel         matlab.ui.control.Label
        VehicleDropDown              matlab.ui.control.DropDown
        StructureAnalysisTab         matlab.ui.container.Tab
        StructureanalysisPanel       matlab.ui.container.Panel
        LCETab                       matlab.ui.container.Tab
        Material2DropDownLabel       matlab.ui.control.Label
        Material2DropDown            matlab.ui.control.DropDown
        DrivecycleDropDownLabel      matlab.ui.control.Label
        DrivecycleDropDown           matlab.ui.control.DropDown
        MaterialDataDropDownLabel    matlab.ui.control.Label
        MaterialDataDropDown         matlab.ui.control.DropDown
        ComputeButton                matlab.ui.control.Button
        ChooseresultSwitchLabel      matlab.ui.control.Label
        ChooseresultSwitch           matlab.ui.control.Switch
        UIAxes2                      matlab.ui.control.UIAxes
        TruckModelTab                matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        ComputationalmodelTab        matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        VolvoFLTab                   matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        drivecycle % Description
        model
        materialData
        var
        net
        routes
        vtype
        vehicles
        time
        sim
    end
    
    methods (Access = private)
        
        function model = setModelMaterial(app,model,materialsData)
            model.beam.E=materialsData.youngsModulus;
            model.beam.rho=materialsData.density;
            model.beam.EProd=materialsData.productionEnergy;
            model.beam.EDisp=materialsData.disposalEnergy;
            model.beam.EEoL=materialsData.eolEnergy;
            model.beam.CO2Prod=materialsData.productionCO2;
            model.beam.CO2Disp=materialsData.disposalCO2;
            model.beam.CO2EoL=materialsData.eolCO2;
            model.beam.Cost=materialsData.price;
        end
        
        %%
        function model=computeOptimalVariable(app,model)
            %this function should determine the optimal values for the geometric
            %variable.
            model.H=abs(model.P*model.L^3/(4*model.E*model.B*model.dmax)).^(1/3);
        end
        
        %%
        function model = computeMass(app,model)
            %this function should determine the mass of the model based on its
            %geometric dimensions and material density.
            
            model.mass.chassi =  (model.beam.H(1,1) * model.beam.B(1,1) + model.beam.H(1,2) * model.beam.B(1,2) + model.beam.H(1,3) * model.beam.B(1,3)) * model.beam.L * model.beam.rho;
            model.mass.tot = model.mass.chassi + sum([model.load.q.mass]);
        end
        
        %% Here we compute the use phase energy throw the drive cycle
        % % Taken from Life cycle energy optimisation: A proposed methodology
        function Eu = compute_LCE_drivecycle(app,drivecycle,model)
            Eu = [];
            % Roling resistanse
            Eu(1,1) = (1 - 0.15)* 9.82 * 0.01 * drivecycle.cr * model.mass.tot;
            % Energy of Inertia
            Eu(1,2) = drivecycle.ca * model.mass.tot;
            % Energy of Drag
            Eu(1,3) = 0.5 * 1.2 * model.shape.Cd * drivecycle.cd * model.shape.A;
            % sum
            Eu = sum(Eu);
            
        end
        
        %% Here we need to change
        function model = computeLCE(app,drivecycle,model)
            model = app.computeMass(model);
            % production phase
            Ep_kg=model.beam.EProd; %[J/kg]
            Ep=sum(Ep_kg.*model.mass.chassi);
            % Use phase
            Eu = app.compute_LCE_drivecycle(drivecycle,model);
            % Disposal phase
            Ed_kg=model.beam.EDisp; %[J/kg]
            Ed=sum(Ed_kg.*model.mass.chassi); %[J]
            % end-of-life phase
            Ee_kg=model.beam.EEoL; %[J/kg]
            Ee=sum(Ee_kg.*model.mass.chassi); %[J]
            % total
            model.LCE=[Ep Eu Ed Ee]*1e-9;
        end
        
        
        function drivecycle = import_data(app)
            
            if ~isstruct(app.DrivecycleDropDown.Value)
                name = app.DrivecycleDropDown.Value;
                data = [];
                data.name = name;
            end
            
            tabledata = [app.DrivecycleDropDown.Value];
            t = readtable(tabledata);
            vel = t.vel/3.6; %km/h to m/s
            t = t.t;
            n=length(t);
            D=spdiags(repmat([-0.5 0 0.5],n,1),-1:1,n,n)+sparse([1 1 n n],[1 2 n-1 n],[-1 0.5 -0.5 1]);
            dt=D*t;
            dx=vel.*dt;
            x=cumsum(dx);
            acc=D*vel./dt;
            r=sum(acc<0)/numel(acc);
            acc(acc<0)=0;
            data.t=t;
            data.x=x;
            data.vel=vel;
            data.acc=acc;
            data.r=r;
            data.cr=sum(dx);
            data.cd=sum(vel.^3.*dt);
            data.ca=sum(acc.*vel.*dt);
            drivecycle = data;
            
        end
        
        
        function driveCycle = computeDrivecycle(app,vehicle)
            t = vehicle.t;
            vel = vehicle.vel; 
            n = length(t); 
            D=spdiags(repmat([-0.5 0 0.5],n,1),-1:1,n,n)+sparse([1 1 n n],[1 2 n-1 n],[-1 0.5 -0.5 1]);
            dt=D*t;
            dx=vel.*dt;
            x=cumsum(dx);
            acc=D*vel./dt;
            r=sum(acc<0)/numel(acc);
            acc(acc<0)=0;
            data.t=t;
            data.x=x;
            data.vel=vel;
            data.acc=acc;
            data.r=r;
            data.cr=sum(dx);
            data.cd=sum(vel.^3.*dt);
            data.ca=sum(acc.*vel.*dt);
            driveCycle = data;
        end
        
        
        %% Here we need to change
        function model = computeLCCO2(app,drive,model)
            if nargin < 2 
                drive.x = model;
            end
            % production phase
            CO2p_kg=model.beam.CO2Prod; %[kg/kg]
            CO2p=sum(CO2p_kg.*model.mass.chassi); %[kg]
            % use phase      %simple model based on fuel efficiency
            CO2u=model.mass.tot * 0.179 * sum(drive.x); %[kg]
            % disposal phase
            CO2d_kg=model.beam.CO2Disp; %[kg/kg]
            CO2d=sum(CO2d_kg.*model.mass.chassi); %[kg]
            % end-of-life phase
            CO2e_kg=model.beam.CO2EoL; %[kg/kg]
            CO2e=sum(CO2e_kg.*model.mass.chassi); %[kg]
            % total
            model.LCCO2=[CO2p CO2u CO2d CO2e]*1e-3;
        end
        %%
        
        
        %%
        function obj=updateDependentVars(app,obj)
            I0=obj.beam.B.*obj.beam.H.^3/12;
            
            d=([0 cumsum(obj.beam.H(1:end-1))]+ obj.beam.H/2-sum(obj.beam.H)/2);
            
            A=obj.beam.B.*obj.beam.H;
            I=I0+A.*d.^2;
            obj.beam.A=sum(A);
            obj.beam.I=sum(I);
            obj.load.q(1).x = [0 obj.beam.L];
            obj.load.q(1).fun = str2func(['@(x)',mat2str(-obj.beam.rho*obj.beam.A*9.81),'+0.*x']);  %Point load
            for i = 2:numel(obj.load.q)
                obj.load.q(i).fun = str2func(['@(x)',mat2str(-obj.load.q(i).mass/abs(diff(obj.load.q(i).x))*9.81),'+0.*x']);
                obj.load.q(i).H = obj.load.q(i).mass/obj.load.q(i).rho/abs(diff(obj.load.q(i).x))/obj.shape.B;
            end
            obj.load.H = max([obj.load.q.H]);
            obj.shape.H = obj.axel.rad + sum(obj.beam.H) + obj.load.H;
            obj.shape.L = obj.beam.L;
            obj.shape.A = sqrt(obj.shape.L^2+obj.shape.H^2);
        end
        
        %%
        function dispTruck(app,obj)
            C=colormap(app.UIAxes);
            r=obj.axel.rad;
            for i = 1:2 % Displays wheels
                x = obj.axel.R(i).x;
                R= rectangle(app.UIAxes,'Position',[x-r 0 2*r 2*r],'Curvature',[1 1]);
                set(R,'Facecolor','k')
            end
            h0 = 2*r;
            for i = 1:3 %Display the sandwich model
                h = obj.beam.H(i);
                L = obj.beam.L;
                R=rectangle(app.UIAxes,'Position',[0 h0 L h]);
                set(R,'Facecolor','g')
                h0 = h0 + h;
            end
            for i = 2:numel(obj.load.q) %display the load
                R=rectangle(app.UIAxes,'Position',[obj.load.q(i).x(1) h0 obj.load.q(i).x(2)-obj.load.q(i).x(1) obj.load.q(i).H]);
                set(R,'Facecolor',C(50*(i-1)+1,:))
            end
            rectangle(app.UIAxes,'Position',[0 r obj.beam.L obj.shape.H],'LineStyle','--')
            axis(app.UIAxes,'equal')
            xlabel(app.UIAxes,'l [m]'), ylabel(app.UIAxes,'h [m]')
            title(app.UIAxes,'Truck Model')
            
        end
        
        %%
        function obj = computeReactions(app,obj)
            F = arrayfun(@(y) integral(y.fun,y.x(1),y.x(2)),obj.load.q);
            
            M = arrayfun(@(y) integral(@(x)y.fun(x).*x,y.x(1),y.x(2)),obj.load.q);
            
            r = [obj.axel.R(:).x];
            
            invA = [-r(2)/(r(1) - r(2)),  1/(r(1) - r(2))
                r(1)/(r(1) - r(2)), -1/(r(1) - r(2))];
            a = -invA*[sum(F);sum(M)];
            obj.axel.R(1).a = a(1);
            obj.axel.R(2).a = a(2);
        end
        
        %%
        function obj = evaluateBeamFuncs(app,obj)
            
            Q=sum([arrayfun(@(y) y.a*heaviside(sym('x')-y.x),obj.axel.R),...
                arrayfun(@(y) int(sym(y.fun),y.x(1),sym('x'))*heaviside(sym('x')-y.x(1)),obj.load.q),...
                arrayfun(@(y) -int(sym(y.fun),y.x(2),sym('x'))*heaviside(sym('x')-y.x(2)),obj.load.q)]);
            
            
            M=int(Q,0,sym('x'));
            T=(int(M,0,sym('x'))-int(int(M,0,sym('x')),obj.axel.R(1).x,obj.axel.R(2).x)/(obj.axel.R(2).x-obj.axel.R(1).x))/obj.beam.E/obj.beam.I;
            W=int(T,obj.axel.R(1).x,sym('x'));
            obj.beam.shear = matlabFunction(Q);
            obj.beam.moment = matlabFunction(M);
            obj.beam.curve = matlabFunction(T);
            obj.beam.delta = matlabFunction(W);
        end
        
        %% display beam functions
        function dispBeamFuncs(app,model_1,legends)
            t = tiledlayout(app.StructureanalysisPanel,2,2);
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.shear,[0 model_1.beam.L])
            hold(ax1,"on")
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'shear force'), legend(ax1,legends),
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.moment,[0 model_1.beam.L])
            hold(ax1,"on")
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'bending moment'),legend(ax1,legends),
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.curve,[0 model_1.beam.L])
            hold(ax1,"on")
            grid(ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'curvature'), legend(ax1,legends),
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.delta,[0 model_1.beam.L])
            hold(ax1,"on")
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'delta'),legend(ax1,legends),
            hold(ax1,"off")
        end


        %%
        function materialData = importMaterial_Data(app)
            % Setup the Import Options and import the data
            clear opts
            opts = spreadsheetImportOptions("NumVariables", 13);
            
            % Specify sheet and range
            opts.Sheet = "Sheet1";
            opts.DataRange = "A3:M11";
            
            % Specify column names and types
            opts.VariableNames = ["names", "price", "density", "youngsModulus", "poissonsRatio", "yieldStrength", "tensileStrength", "productionEnergy", "productionCO2", "disposalEnergy", "disposalCO2", "eolEnergy", "eolCO2"];
            opts.VariableTypes = ["string", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "names", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "names", "EmptyFieldRule", "auto");
            
            % Import the data
            tabledata = [app.MaterialDataDropDown.Value];
            materialData=readtable(tabledata,opts, "UseExcel", false);
            %materialData=table2struct(materialData);
            
        end
        
        %%

        %%
        function bargraf(app,result,showtotal)
            
            if nargin==3
                cla(app.UIAxes2,"reset");
                result(1).values=[result(1).values;sum([result(1).values],1)];
                bar(app.UIAxes2,result(1).values,'grouped')
                result(1).xlabel=[result(1).xlabel,{'Total'}];
                bar(app.UIAxes2,result(1).values,'grouped')
                a=axis(app.UIAxes2);
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,repmat(size(result(1).values,1)-0.5,1,2),[a(3) a(4)]*10,'-k')
                axis(app.UIAxes2,a)
                hold (app.UIAxes2,"off")
                
            elseif size(result(1).values,1)==1 & size(result(1).values,2)>1
                cla(app.UIAxes2,"reset");
                bar(app.UIAxes2,[result(1).values;result(1).values],'grouped')
                a=axis(app.UIAxes2);
                a(2)=a(2)-1;
                axis(app.UIAxes2,a)
            else
                bar(app.UIAxes2,result(1).values,'grouped')
            end
            
            title(app.UIAxes2, result(1).title);
            %xlabel(app.UIAxes, result(1).xlabel)
            ylabel(app.UIAxes2, result(1).ylabel)
            legend(app.UIAxes2,{result.legend},'location','bestoutside');
            set(app.UIAxes2,'xticklabel',result(1).xlabel)
            
        end
        
        function G = initNet(app,x,y,speed)
            nx = numel(x);
            ny = numel(y);
            [x,y] = meshgrid(x,y);
            nodes = [reshape(x',numel(x),1) reshape(y',numel(y),1)];
            edges = [];
            for i = 1:nx*ny
                if mod(i,nx)
                    edges = [edges; i i+1];
                end
                if i<(nx*(ny-1)+1)
                    edges = [edges; i i+nx];
                end
            end
            edges = [edges; fliplr(edges)];
            speed = speed*ones(size(edges,1),1);
            EdgeTable = table(edges,'VariableNames',{'EndNodes'});
            EdgeTable.Speed = speed;
            NodeTable = array2table(nodes,'VariableNames',{'X' 'Y'});
            G = digraph(EdgeTable,NodeTable);
        end
        
        function G = joinNets(app,G,G1,conn,speed)
            nnds = size(G.Nodes,1);
            G = addnode(G,G1.Nodes);
            EdgeTable=G1.Edges;
            EdgeTable.EndNodes=EdgeTable.EndNodes+nnds;
            G = addedge(G,EdgeTable);
            EdgeTable = table([conn(:,1) conn(:,2)+nnds; conn(:,2)+nnds conn(:,1)],'VariableNames',{'EndNodes'});
            EdgeTable.Speed = speed*ones(size(conn,1)*2,1);
            G = addedge(G,EdgeTable);
        end
        
        function drawGraph(app,G,routes)
            plot(app.UIAxes4, G,'XData',G.Nodes.X,'YData',G.Nodes.Y)
            if exist('routes')
                hold(app.UIAxes4,"on");
                for i = 1:numel(routes.nstop)
                    plot(app.UIAxes4,G.Nodes.X(routes.nstop{i}),G.Nodes.Y(routes.nstop{i}),'o','MarkerSize',10,'MarkerFaceColor',[1 0 0])
                    plot(app.UIAxes4,G.Nodes.X(G.Edges.EndNodes(routes.edges{i},:)),G.Nodes.Y(G.Edges.EndNodes(routes.edges{i},:)),'LineWidth',2)
                end
                hold(app.UIAxes4,"off");
            end
        end
        
        function G = net2Graph(app,net)
            NodeTable = array2table(net.nodes,'VariableNames',{'X' 'Y'});
            EdgeTable = table(net.edges,'VariableNames',{'EndNodes'});
            EdgeTable.Speed = net.speed;
            G = digraph(EdgeTable,NodeTable);
        end
        
        function setStop(app,net,routes,vehicles,duration)
            for v = 1:numel(vehicles)
                for s = routes.estop{vehicles(v).route}
                    traci.vehicle.setStop(['veh' num2str(v)],['e' num2str(s)],1,0,duration)
                end
            end
        end
        
        function obj = initTruck(app,material)
            
            app.var.K = find([app.materialData.names]' == material);
            
            
            obj.beam.L = 10.0;
            obj.beam.H = [0.02 0.1 0.02];
            obj.beam.B = [0.1 0.02 0.1]; % this is not the optimum height, it could be minimised with subject to the load.
            obj.beam.E = app.materialData.youngsModulus(app.var.K,1);
            obj.beam.rho = app.materialData.density(app.var.K,1);
            
            obj.axel.rad = 0.5;
            obj.axel.Crr = 0.01;
            obj.axel.R(1).x = 0.75;
            obj.axel.R(2).x = 6;
            
            obj.shape.B = 2.0;
            obj.shape.Cd = 0.5;
            
            obj.load.q(2).x = [1, 3.75];
            obj.load.q(2).mass = 5e3;
            obj.load.q(2).rho = 5e3;
            
            obj.load.q(3).x = [4, 9];
            obj.load.q(3).mass = 5e3;
            obj.load.q(3).rho = 1e2;
            
        end
        
        function vehicles = initVehicle(app,material)
            
            app.var.K = find([app.materialData.names]' == material);
            
            vehicles.id = '';
            vehicles.type = 1;
            vehicles.route = 1;
            vehicles.depart = 0;
            vehicles.departSpeed = 0;
            vehicles.mass = [];
            vehicles.cr = 0.01;
            vehicles.cd = 0.25;
            vehicles.A = 2*1.5;
            vehicles.lane = {};
            vehicles.t = [];
            vehicles.xy = [];
            vehicles.vel = [];
            vehicles.acc = [];
            vehicles.Wdt = [];
            vehicles.LCO2 =[];
            vehicles.phandle = [];
            
            vehicles.beam.L = 10.0;
            vehicles.beam.H = [0.02 0.1 0.02];
            vehicles.beam.B = [0.1 0.02 0.1]; % this is not the optimum height, it could be minimised with subject to the load.
            vehicles.beam.E = app.materialData.youngsModulus(app.var.K,1);
            vehicles.beam.rho = app.materialData.density(app.var.K,1);
            
            vehicles.axel.rad = 0.5;
            vehicles.axel.Crr = 0.01;
            vehicles.axel.R(1).x = 0.75;
            vehicles.axel.R(2).x = 6;
            
            vehicles.shape.B = 2.0;
            vehicles.shape.Cd = 0.5;
            
            vehicles.load.q(2).x = [1, 3.75];
            vehicles.load.q(2).mass = 5e3;
            vehicles.load.q(2).rho = 5e3;
            
            vehicles.load.q(3).x = [4, 9];
            vehicles.load.q(3).mass = 5e3;
            vehicles.load.q(3).rho = 1e2;
            
        end


        function time=initTime(app,t0,t1,dt)
            time.t = t0;
            time.start = t0;
            time.stop = t1;
            time.dt = dt;
        end
        
        function route = initRoute(app,nstop)
            route.nstop = nstop;
            route.nodes = [];
            route.estop = [];
            route.edges = [];
        end
        
        function route = findRoute(app,route,net)
            for r = 1:numel(route.nstop)
                stops = route.nstop{r};
                nodes = [];
                costs = 0;
                for s = 1:numel(stops)-1
                    [cost,node] = app.dijkstra(full(adjacency(net)),stops(s),stops(s+1));
                    nodes = [nodes fliplr(node)];
                    costs = costs + cost;
                end
                nodes = nodes(nodes~=[nodes(2:end) 0]);
                edges = [];
                for i=1:numel(nodes)-1
                    edges = [edges find(ismember(net.Edges.EndNodes,nodes(i:i+1),'rows'))];
                end
                estop = edges(ismember(nodes,stops(2:end-1)));
                route.nodes = [route.nodes; {nodes}];
                route.edges = [route.edges; {edges}];
                route.estop = [route.estop; {estop}];
            end
        end
        
        function vtype = initVType(app)
            vtype.id = 'Car';
            vtype.length = 2;
            vtype.accel = 1;
            vtype.decel = 2;
            vtype.maxSpeed = 150/3.6;
            vtype.sigma = 0.0;
        end
        
        function writeNodes(app,file,nodes)
            fid=fopen([file,'.nod.xml'],'w');
            fprintf(fid,'%s\n','<nodes>');
            for i=1:size(nodes,1)
                fprintf(fid,'%s\n',['    <node id="n',num2str(i),'" x="',num2str(nodes(i,1)),'" y="',num2str(nodes(i,2)),'" />']);
            end
            fprintf(fid,'%s','</nodes>');
            fclose(fid);
        end
        
        function writeEdges(app,file,edges,speed)
            fid=fopen([file,'.edg.xml'],'w');
            fprintf(fid,'%s\n','<edges>');
            for i=1:size(edges,1)
                fprintf(fid,'%s\n',['    <edge from="n',num2str(edges(i,1)),'" id="e',num2str(i),'" to="n',num2str(edges(i,2)),'" speed="',num2str(speed(i)),'" />']);
            end
            fprintf(fid,'%s','</edges>');
            fclose(fid);
        end
        
        function writeRoute(app,file,routes,vtype,vehicles)
            route = [];
            for r = 1:numel(routes.edges)
                str = [];
                for i = 1:numel(routes.edges{r})
                    str = [str,'e',num2str(routes.edges{r}(i)),' '];
                end
                route = [route; {str}];
            end
            fid=fopen([file,'.rou.xml'],'w');
            fprintf(fid,'%s\n','<routes>');
            for i = 1:numel(route)
                fprintf(fid,'%s\n',['    <route id="route',num2str(i),'" edges="',route{i}(1:end-1),'"/>']);
            end
            for i=1:numel(vtype)
                fprintf(fid,'%s\n',['    <vType id="',vtype(i).id,'" accel="',num2str(vtype(i).accel),'" decel="',num2str(vtype(i).decel),'" length="',num2str(vtype(i).length),'" maxSpeed="',num2str(vtype(i).maxSpeed),'" sigma="',num2str(vtype(i).sigma),'" />']);
            end
            for i=1:numel(vehicles)
                fprintf(fid,'%s\n',['    <vehicle id="',num2str(vehicles(i).id),'" type="',vtype(vehicles(i).type).id,'" route="route',num2str(vehicles(i).route),'" depart="',num2str(vehicles(i).depart),'" departSpeed="',num2str(vehicles(i).departSpeed),'" />']);
            end
            fprintf(fid,'%s','</routes>');
            fclose(fid);
        end
        
        function writeConf(app,file,time)
            start=time.start;
            stop=time.stop;
            dt=time.dt;
            fid=fopen([file,'.sumocfg'],'w');
            fprintf(fid,'%s\n','<configuration>');
            fprintf(fid,'%s\n','      <input>');
            fprintf(fid,'%s\n',['         <net-file value="',file,'.net.xml"/>']);
            fprintf(fid,'%s\n',['         <route-files value="',file,'.rou.xml"/>']);
            fprintf(fid,'%s\n','      </input>');
            fprintf(fid,'%s\n','      <time>');
            fprintf(fid,'%s\n',['         <begin value="',num2str(start),'"/>']);
            fprintf(fid,'%s\n',['         <end value="',num2str(stop),'"/>']);
            fprintf(fid,'%s\n','      </time>');
            fprintf(fid,'%s\n',['      <step-length value = "',num2str(dt),'"/>']);
            fprintf(fid,'%s','</configuration>');
            fclose(fid);
        end
        
        function obj = initObj(app)
            obj.id = [];
            %   obj.type = [];
            obj.xy = [];
        end
        
        function net = getNet(app)
            nodes = app.initObj();
            lanes = app.initObj();
            for i = traci.junction.getIDList()
                nodes.id = [nodes.id; {i{:}}];
                nodes.xy = [nodes.xy; traci.junction.getPosition(i{:})];
            end
            for i = traci.lane.getIDList
                if not(i{:}(1)==':')
                    lanes.id = [lanes.id; {i{:}}];
                    lanes.xy = [lanes.xy; traci.lane.getShape(i{:})];
                end
            end
            net.nodes = nodes;
            net.lanes = lanes;
        end
        
        function drawNet(app,net)               
            plot(app.UIAxes3,net.nodes.xy(:,1),net.nodes.xy(:,2),'.k')
            for i = 1:numel(net.lanes.id)
                plot(app.UIAxes3,[net.lanes.xy{i,1}(1) net.lanes.xy{i,2}(1)],[net.lanes.xy{i,1}(2) net.lanes.xy{i,2}(2)],'k')
            end            
        end
        
        function [e L] = dijkstra(app,A,s,d)
            %---------------------------------------------------
            % Dijkstra Algorithm
            % author : Dimas Aryo
            % email : mr.dimasaryo@gmail.com
            %
            % usage
            % [cost rute] = dijkstra(Graph, source, destination)
            %
            % example
            % G = [0 3 9 0 0 0 0;
            %      0 0 0 7 1 0 0;
            %      0 2 0 7 0 0 0;
            %      0 0 0 0 0 2 8;
            %      0 0 4 5 0 9 0;
            %      0 0 0 0 0 0 4;
            %      0 0 0 0 0 0 0;
            %      ];
            % [e L] = dijkstra(G,1,7)
            %---------------------------------------------------
            
            if s==d
                e=0;
                L=[s];
            else
                
                A = app.setupgraph(A,inf,1);
                
                if d==1
                    d=s;
                end
                A=app.exchangenode(A,1,s);
                
                lengthA=size(A,1);
                W=zeros(lengthA);
                for i=2 : lengthA
                    W(1,i)=i;
                    W(2,i)=A(1,i);
                end
                
                for i=1 : lengthA
                    D(i,1)=A(1,i);
                    D(i,2)=i;
                end
                
                D2=D(2:length(D),:);
                L=2;
                while L<=(size(W,1)-1)
                    L=L+1;
                    D2=sortrows(D2,1);
                    k=D2(1,2);
                    W(L,1)=k;
                    D2(1,:)=[];
                    for i=1 : size(D2,1)
                        if D(D2(i,2),1)>(D(k,1)+A(k,D2(i,2)))
                            D(D2(i,2),1) = D(k,1)+A(k,D2(i,2));
                            D2(i,1) = D(D2(i,2),1);
                        end
                    end
                    
                    for i=2 : length(A)
                        W(L,i)=D(i,1);
                    end
                end
                if d==s
                    L=[1];
                else
                    L=[d];
                end
                e=W(size(W,1),d);
                L = app.listdijkstra(L,W,s,d);
            end
            
        end
        
        function G = setupgraph(app,G,b,s)
            
            if s==1
                for i=1 : size(G,1)
                    for j=1 :size(G,1)
                        if G(i,j)==0
                            G(i,j)=b;
                        end
                    end
                end
            end
            if s==2
                for i=1 : size(G,1)
                    for j=1 : size(G,1)
                        if G(i,j)==b
                            G(i,j)=0;
                        end
                    end
                end
            end
            
        end
        function G = exchangenode(app,G,a,b)
            
            %Exchange element at column a with element at column b;
            buffer=G(:,a);
            G(:,a)=G(:,b);
            G(:,b)=buffer;
            
            %Exchange element at row a with element at row b;
            buffer=G(a,:);
            G(a,:)=G(b,:);
            G(b,:)=buffer;
            
        end
        
        function L = listdijkstra(app,L,W,s,d)
            
            index=size(W,1);
            while index>0
                if W(2,d)==W(size(W,1),d)
                    L=[L s];
                    index=0;
                else
                    index2=size(W,1);
                    while index2>0
                        if W(index2,d)<W(index2-1,d)
                            L=[L W(index2,1)];
                            L=app.listdijkstra(L,W,s,W(index2,1));
                            index2=0;
                        else
                            index2=index2-1;
                        end
                        index=0;
                    end
                end
            end
            
        end
        
        function drawVehicle(app,vehicle)
            
            if isempty(vehicle.phandle)
                h = plot(app.UIAxes3,vehicle.xy(1),vehicle.xy(2),'ob');
                set(h,'MarkerFaceColor',get(h,'Color'))
                app.vehicles(find(ismember([{app.vehicles.id}],vehicle.id))).phandle =  h;
            else
                set(vehicle.phandle,'XData',vehicle.xy(end,1))
                set(vehicle.phandle,'YData',vehicle.xy(end,2))
            end
        end


        function showResult(app,K)
            t = tiledlayout(app.ComputationalresultsPanel,3,2);
            
            ax1 = nexttile(t);
            plot(ax1,app.vehicles(K).t,app.vehicles(K).Wdt(:,1))
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'') , hold(ax1,"off") , title(ax1, 'Inertia energy')
            
            ax1 = nexttile(t);
            pie(ax1, [max(cumsum(app.vehicles(K).Wdt(:,:)))])
            legend(ax1,{'Inertia','Rolling','Drag'},'Location','southeastoutside'), title(ax1,'Use phase energy')
            
            ax1 = nexttile(t);
            plot(ax1,app.vehicles(K).t,app.vehicles(K).Wdt(:,2))
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'') , hold(ax1,"off") , title(ax1, 'Rolling Resistance')
            
            ax1 = nexttile(t); 
            fplot(ax1,app.vehicles(K).beam.delta,[0 app.vehicles(K).beam.L])
            hold(ax1,"on")
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'delta'), legend(ax1,{app.ChassimaterialDropDown.Value})
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            plot(ax1,app.vehicles(K).t,app.vehicles(K).Wdt(:,3))
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'') , hold(ax1,"off") , title(ax1, 'Drag')
            
            ax1 = nexttile(t);
            C=colormap(ax1);
            r=app.vehicles(K).axel.rad;
            for i = 1:2 % Displays wheels
                x = app.vehicles(K).axel.R(i).x;
                R= rectangle(ax1,'Position',[x-r 0 2*r 2*r],'Curvature',[1 1]);
                set(R,'Facecolor','k')
            end
            h0 = 2*r;
            for i = 1:3 %Display the sandwich model
                h = app.vehicles(K).beam.H(i);
                L = app.vehicles(K).beam.L;
                R=rectangle(ax1,'Position',[0 h0 L h]);
                set(R,'Facecolor','g')
                h0 = h0 + h;
            end
            for i = 2:numel(app.vehicles(K).load.q) %display the load
                R=rectangle(ax1,'Position',[app.vehicles(K).load.q(i).x(1) h0 app.vehicles(K).load.q(i).x(2)-app.vehicles(K).load.q(i).x(1) app.vehicles(K).load.q(i).H]);
                set(R,'Facecolor',C(50*(i-1)+1,:))
            end
            rectangle(ax1,'Position',[0 r app.vehicles(K).beam.L app.vehicles(K).shape.H],'LineStyle','--')
            axis(ax1,'equal')
            xlabel(ax1,'l [m]'), ylabel(ax1,'h [m]')
            title(ax1,'Truck Model')


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes3.Visible = 'off';
            app.NumberofdeliverystationsDropDown.Items = {'', '1','2','3'}; 
            DrivecycleDropDownValueChanged(app);            
            app.materialData = importMaterial_Data(app);
            Material1DropDownValueChanged(app);
            
            
        end

        % Value changed function: DrivecycleDropDown, 
        % MaterialDataDropDown
        function DrivecycleDropDownValueChanged(app, event)
            D = dir("WLTP*.xlsx");
            app.DrivecycleDropDown.Items = {D.name};
            S = dir("materialData.xlsx");
            app.MaterialDataDropDown.Items = {S.name};
            
        end

        % Value changed function: ChassimaterialDropDown, 
        % Material2DropDown
        function Material1DropDownValueChanged(app, event)
            app.ChassimaterialDropDown.Items = app.materialData.names;
            app.Material2DropDown.Items = app.materialData.names;
        end

        % Callback function: ChooseresultSwitch, ComputeButton
        function ComputeButtonPushed(app, event)
            app.Image.ImageSource = imread('https://www.volvotrucks.se/sv-se/trucks/trucks/volvo-fl/volvo-fl-electric/_jcr_content/root/responsivegrid/columncontrol_1535929993/parsys1/image.coreimg.jpeg/1601564446161/volvo-trucks-fl-electric-infrastructure.jpeg','jpeg');
            
            
            DrivecycleDropDownValueChanged(app);
            app.drivecycle = import_data(app);
            
            
            value_1 = app.ChassimaterialDropDown.Value;
            value_2 = app.Material2DropDown.Value;
            comparison_type = app.ChooseresultSwitch.Value;
            
            legends = {value_1 value_2};
            
            app.model.model_1 = app.initTruck(value_1);
            app.model.model_1 = app.setModelMaterial(app.model.model_1,app.materialData(app.var.K,:));
            app.model.model_1 = app.updateDependentVars(app.model.model_1);
            app.model.model_1 = app.computeMass(app.model.model_1);      
            app.model.model_1 = app.computeReactions(app.model.model_1);
            app.model.model_1 = app.evaluateBeamFuncs(app.model.model_1);
            app.model.model_1 = app.computeLCE(app.drivecycle,app.model.model_1);
            app.model.model_1 = app.computeLCCO2(app.drivecycle,app.model.model_1);
            
            app.model.model_2 = app.initTruck(value_2);
            app.model.model_2 = app.setModelMaterial(app.model.model_2,app.materialData(app.var.K,:));
            app.model.model_2 = app.updateDependentVars(app.model.model_2);
            app.model.model_2 = app.computeMass(app.model.model_2);
            app.model.model_2 = app.computeLCE(app.drivecycle,app.model.model_2);
            app.model.model_2 = app.computeLCCO2(app.drivecycle,app.model.model_2);
            app.model.model_2 = app.computeReactions(app.model.model_2);
            app.model.model_2 = app.evaluateBeamFuncs(app.model.model_2);
            
            %app.dispBeamFuncs(app.model.model_1,app.model.model_2,legends);
            %app.dispTruck(app.model.model_1);
            
            switch comparison_type
                case 'CO2'
                    Luring = [app.model.model_1.LCCO2', app.model.model_2.LCCO2'];
                    bargraf(app,struct('title','CO_2','xlabel',{{'Prod','Use','EoL Pot'}},'ylabel','[kg]','legend',legends,'values',Luring),1);
                otherwise
                    Luring = [app.model.model_1.LCE', app.model.model_2.LCE'];
                    bargraf(app,struct('title','Energy','xlabel',{{'Prod','Use','EoL Pot'}},'ylabel','[J]','legend',legends,'values',Luring),1);
            end


        end

        % Callback function: LogEditField, StartButton
        function StartButtonPushed(app, event)
            
            app.UIAxes3.Visible = 'on';
            app.routes = [];
            app.vehicles = []; 
            modelname='model';
            
            %% Inputs and variables
            setenv('SUMO_HOME','/usr/local/opt/sumo/share/sumo/bin/')
            scenarioPath = ['./model.sumocfg '];
            sumoArgs = '--remote-port 8813 & ';
            
            % Initiate structures
            app.net = app.initNet([-1000 linspace(0,400,3)],0,100/3.6);
            app.net = app.joinNets(app.net,app.initNet(linspace(0,400,5),linspace(0,200,3)+50,30/3.6),[2 1; 3 3; 4 5],70/3.6);
            
            
            app.routes = app.NumberofdeliverystationsDropDown.Value; 
            
            switch app.routes
                case '1'
                    r1 = randi([1 19],1,3);
                    r1(1,1) = 1; r1(1,end) = 1;
                    r2 = randi([1 19],1,3);
                    r2(1,1) = 1; r2(1,end) = 1;
                    app.routes = app.initRoute([{[r1]} ; {[r2]}]); 
                    app.routes = app.findRoute(app.routes,app.net);
                case '2'
                    r1 = randi([1 19],1,4);
                    r1(1,1) = 1; r1(1,end) = 1;
                    r2 = randi([1 19],1,4);
                    r2(1,1) = 1; r2(1,end) = 1;
                    app.routes = app.initRoute([{[r1]} ; {[r2]}]); 
                    app.routes = app.findRoute(app.routes,app.net);
                case '3'
                    r1 = randi([1 19],1,5);
                    r1(1,1) = 1; r1(1,end) = 1;
                    r2 = randi([1 19],1,5);
                    r2(1,1) = 1; r2(1,end) = 1;
                    app.routes = app.initRoute([{[r1]} ; {[r2]}]); 
                    app.routes = app.findRoute(app.routes,app.net);
                otherwise
                    app.LogEditField.Value =  sprintf('Select number of delivery stations');
                    return;
                    
            end
            
            %ComputeButtonPushed(app); 
            
            app.vtype = app.initVType;
            selectedMaterial = app.ChassimaterialDropDown.Value; 

            
            for i = 1:2
                app.vehicles = [app.vehicles; app.initVehicle(selectedMaterial)];
                app.vehicles(i).id = ['veh',num2str(i)];
                app.vehicles(i).depart = (i-1)*25;
                app.vehicles(i).departSpeed = 110/3.6;
                app.vehicles(i).route = i;
                app.vehicles(i) = app.setModelMaterial(app.vehicles(i),app.materialData(app.var.K,:));
                app.vehicles(i) = app.updateDependentVars(app.vehicles(i));
                app.vehicles(i) = app.computeMass(app.vehicles(i));
                app.vehicles(i) = app.computeReactions(app.vehicles(i));
                app.vehicles(i) = app.evaluateBeamFuncs(app.vehicles(i));
            end
            
            app.time = app.initTime(0,200,1);
            
            
            app.drawGraph(app.net,app.routes); % draw the path
            axis(app.UIAxes4,"equal");
            title(app.UIAxes4, {['veh1:[' num2str(r1(1,2:end-1)) '] ' 'veh2:[' num2str(r2(1,2:end-1)) ']']})
            
            %% write files
            app.LogEditField.Value = sprintf('Writing SUMO files... \t');
            app.writeNodes(modelname,[app.net.Nodes.X app.net.Nodes.Y])
            app.writeEdges(modelname,app.net.Edges.EndNodes,app.net.Edges.Speed)
            app.writeRoute(modelname,app.routes,app.vtype,app.vehicles)
            writeConf(app,modelname,app.time)
            system([getenv('SUMO_HOME') 'netconvert --node-files=',modelname,'.nod.xml --edge-files=',modelname,'.edg.xml --output-file=',modelname,'.net.xml']);
            
            %Run
            app.LogEditField.Value = sprintf('Initializing\n');
            system([getenv('%SUMO_HOME%') 'sumo -c ' scenarioPath  sumoArgs]);
            [traciVersion,sumoVersion] = traci.init();
            app.LogEditField.Value = sprintf('SUMO version: %s\nTraCI version: %d\n',sumoVersion,traciVersion);
            
            netsim = app.getNet();
            hold(app.UIAxes3,"on"); axis(app.UIAxes3, "equal"); axis(app.UIAxes3, "off"); app.drawNet(netsim)
            tic
            
            app.LogEditField.Value = sprintf('Running...  \t');
            traci.simulation.step();
            dt = traci.simulation.getDeltaT();
            step = 1;
            app.setStop(app.net,app.routes,app.vehicles,40);
            
            while numel(traci.vehicle.getIDList)>0
                step = step + 1;
                app.time.t = [app.time.t; traci.simulation.getTime()];
                
                for v = traci.vehicle.getIDList()
                    if not(ismember(v,[{app.vehicles.id}]))
                        if isempty(app.vehicles(end).id)
                            app.vehicles(end).id = v{:};
                        else
                            app.vehicles(end+1).id = v{:};
                        end
                    end
                    [~,i] = ismember(v,[{app.vehicles.id}]);
                    xy = traci.vehicle.getPosition(v{:});
                    vel = traci.vehicle.getSpeed(v{:});
                    acc = traci.vehicle.getAcceleration(v{:});
                    app.vehicles(i).t(end+1,:) = traci.simulation.getTime();
                    app.vehicles(i).xy(end+1,:) = xy;
                    app.vehicles(i).vel(end+1,:) = vel;
                    app.vehicles(i).acc(end+1,:) = acc;

                    % Energy of Inertia  
                    Wdt_a = app.vehicles(i).mass.tot*acc*vel*dt;
                    % Roling resistanse
                    r=sum(acc<0)/numel(acc);
                    Wdt_r = (1-r)*9.81* app.vehicles(i).cr *app.vehicles(i).mass.tot *vel*dt;
                    % Energy of Drag
                    Wdt_d = 0.5* 1.2* app.vehicles(i).cd *app.vehicles(i).shape.A * vel^3*dt;
                    
                    app.vehicles(i).Wdt(end+1,:) = [max([0 Wdt_a]) Wdt_r Wdt_d];
                    
                    app.drawVehicle(app.vehicles(i))
                    if traci.vehicle.getStopState(v{:})
                        app.vehicles(i).mass.tot = app.vehicles(i).mass.tot - 50* dt; % we have to change
                    end
                end
                drawnow;
                
                
                for v = setdiff([{app.vehicles.id}],traci.vehicle.getIDList())
                    [~,i] = ismember(v,[{app.vehicles.id}]);
                    delete(app.vehicles(i).phandle);
                end
                traci.simulation.step();
                
            end
            
            
            for v = setdiff([{app.vehicles.id}],traci.vehicle.getIDList())
                [~,i] = ismember(v,[{app.vehicles.id}]);
                delete(app.vehicles(i).phandle);
            end
            
            app.LogEditField.Value = sprintf('finished\n');            
            traci.close();
            TTT = toc;
            app.LogEditField.Value = sprintf('time is %d\n',TTT);
            
            app.VehicleDropDown.Items = {'', app.vehicles(:).id};
            app.UIAxes3.Visible = 'off';
            
            app.dispBeamFuncs(app.vehicles(1),selectedMaterial);


        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
            selectedVehicle = app.VehicleDropDown.Value;
            
            for i = 1:2
                if app.vehicles(i).id == selectedVehicle
                    app.showResult(i);
                    break;
                end
            end


        end

        % Callback function: NumberofdeliverystationsDropDown, 
        % NumberofdeliverystationsDropDown
        function NumberofdeliverystationsDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ABSimulationTab = uitab(app.TabGroup);
            app.ABSimulationTab.Title = 'AB-Simulation';
            app.TabGroup3 = uitabgroup(app.ABSimulationTab);
            app.SimulationtabTab = uitab(app.TabGroup3);
            app.SimulationtabTab.Title = 'Simulation tab';
            app.StartButton = uibutton(app.SimulationtabTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LogEditFieldLabel = uilabel(app.SimulationtabTab);
            app.LogEditFieldLabel.Text = 'Log';
            app.LogEditField = uieditfield(app.SimulationtabTab, 'text');
            app.LogEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.NumberofdeliverystationsDropDownLabel = uilabel(app.SimulationtabTab);
            app.NumberofdeliverystationsDropDownLabel.Text = 'Number of delivery stations ';
            app.NumberofdeliverystationsDropDown = uidropdown(app.SimulationtabTab);
            app.NumberofdeliverystationsDropDown.Items = {};
            app.NumberofdeliverystationsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NumberofdeliverystationsDropDownOpening, true);
            app.NumberofdeliverystationsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofdeliverystationsDropDownOpening, true);
            app.NumberofdeliverystationsDropDown.Value = {};
            app.ChassimaterialDropDownLabel = uilabel(app.SimulationtabTab);
            app.ChassimaterialDropDownLabel.Text = 'Chassi material';
            app.ChassimaterialDropDown = uidropdown(app.SimulationtabTab);
            app.ChassimaterialDropDown.Items = {};
            app.ChassimaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @Material1DropDownValueChanged, true);
            app.ChassimaterialDropDown.Value = {};
            app.UIAxes3 = uiaxes(app.SimulationtabTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RouteTab = uitab(app.TabGroup3);
            app.RouteTab.Title = 'Route';
            app.UIAxes4 = uiaxes(app.RouteTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ResultTab = uitab(app.TabGroup3);
            app.ResultTab.Title = 'Result';
            app.ComputationalresultsPanel = uipanel(app.ResultTab);
            app.ComputationalresultsPanel.Title = 'Computational results ';
            app.VehicleDropDownLabel = uilabel(app.ResultTab);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.ResultTab);
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.StructureAnalysisTab = uitab(app.TabGroup);
            app.StructureAnalysisTab.Title = 'Structure Analysis';
            app.StructureanalysisPanel = uipanel(app.StructureAnalysisTab);
            app.StructureanalysisPanel.Title = 'Structure analysis';
            app.LCETab = uitab(app.TabGroup);
            app.LCETab.Title = 'LCE';
            app.Material2DropDownLabel = uilabel(app.LCETab);
            app.Material2DropDownLabel.Text = 'Material #2';
            app.Material2DropDown = uidropdown(app.LCETab);
            app.Material2DropDown.ValueChangedFcn = createCallbackFcn(app, @Material1DropDownValueChanged, true);
            app.DrivecycleDropDownLabel = uilabel(app.LCETab);
            app.DrivecycleDropDownLabel.Text = 'Drive cycle';
            app.DrivecycleDropDown = uidropdown(app.LCETab);
            app.DrivecycleDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivecycleDropDownValueChanged, true);
            app.MaterialDataDropDownLabel = uilabel(app.LCETab);
            app.MaterialDataDropDownLabel.Text = 'Material Data';
            app.MaterialDataDropDown = uidropdown(app.LCETab);
            app.MaterialDataDropDown.Items = {};
            app.MaterialDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivecycleDropDownValueChanged, true);
            app.MaterialDataDropDown.Value = {};
            app.ComputeButton = uibutton(app.LCETab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.ChooseresultSwitchLabel = uilabel(app.LCETab);
            app.ChooseresultSwitchLabel.Text = 'Choose result';
            app.ChooseresultSwitch = uiswitch(app.LCETab, 'slider');
            app.ChooseresultSwitch.Items = {'CO2', 'Energy'};
            app.ChooseresultSwitch.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ChooseresultSwitch.Value = 'CO2';
            app.UIAxes2 = uiaxes(app.LCETab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.TruckModelTab = uitab(app.TabGroup);
            app.TruckModelTab.Title = 'Truck Model';
            app.TabGroup2 = uitabgroup(app.TruckModelTab);
            app.ComputationalmodelTab = uitab(app.TabGroup2);
            app.ComputationalmodelTab.Title = 'Computational model';
            app.UIAxes = uiaxes(app.ComputationalmodelTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VolvoFLTab = uitab(app.TabGroup2);
            app.VolvoFLTab.Title = 'Volvo FL';
            app.Image = uiimage(app.VolvoFLTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECO_design_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end